classdef fe_app_oop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        SuiteMenu                 matlab.ui.container.Menu
        CardMenu                  matlab.ui.container.Menu
        ToolsMenu                 matlab.ui.container.Menu
        LinkvideoMenu             matlab.ui.container.Menu
        EventfilterMenu           matlab.ui.container.Menu
        PeakanalysisMenu          matlab.ui.container.Menu
        TabGroup2                 matlab.ui.container.TabGroup
        CreatTab_2                matlab.ui.container.Tab
        UITable4                  matlab.ui.control.Table
        Tab2                      matlab.ui.container.Tab
        TabGroup                  matlab.ui.container.TabGroup
        CreatTab                  matlab.ui.container.Tab
        CreatButton               matlab.ui.control.Button
        CompleteLamp              matlab.ui.control.Lamp
        CompleteLampLabel         matlab.ui.control.Label
        EventPanel                matlab.ui.container.Panel
        PageDropDown              matlab.ui.control.DropDown
        PageDropDownLabel         matlab.ui.control.Label
        LoadtableButton           matlab.ui.control.Button
        EndEditField              matlab.ui.control.NumericEditField
        EndEditFieldLabel         matlab.ui.control.Label
        StartEditField            matlab.ui.control.NumericEditField
        StartEditFieldLabel       matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        AddButton                 matlab.ui.control.Button
        Loadevent_tagButton       matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        SignalPanel               matlab.ui.container.Panel
        SamplerateEditField       matlab.ui.control.NumericEditField
        SamplerateEditFieldLabel  matlab.ui.control.Label
        ColumnDropDown            matlab.ui.control.DropDown
        ColumnDropDownLabel       matlab.ui.control.Label
        LoadcsvButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        MicePanel                 matlab.ui.container.Panel
        mice_idEditField          matlab.ui.control.EditField
        mice_idEditFieldLabel     matlab.ui.control.Label
        experimentEditField       matlab.ui.control.EditField
        experimentEditFieldLabel  matlab.ui.control.Label
        batchEditField            matlab.ui.control.EditField
        batchEditFieldLabel       matlab.ui.control.Label
        ModifyTab                 matlab.ui.container.Tab
        Lamp                      matlab.ui.control.Lamp
        DetrenderPanel            matlab.ui.container.Panel
        DetrendLamp               matlab.ui.control.Lamp
        DetrendLampLabel          matlab.ui.control.Label
        MethodButtonGroup         matlab.ui.container.ButtonGroup
        SplineButton              matlab.ui.control.ToggleButton
        PolyfitButton             matlab.ui.control.ToggleButton
        Knob_2                    matlab.ui.control.Knob
        UIAxes4                   matlab.ui.control.UIAxes
        SignalPanel_2             matlab.ui.container.Panel
        ConfirmButton             matlab.ui.control.Button
        AfterEditField            matlab.ui.control.NumericEditField
        AfterEditFieldLabel       matlab.ui.control.Label
        PreEditField_2            matlab.ui.control.NumericEditField
        PreEditField_2Label       matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        EventPanel_2              matlab.ui.container.Panel
        DropDown_3                matlab.ui.control.DropDown
        NoneButton                matlab.ui.control.Button
        AllButton                 matlab.ui.control.Button
        DropDown                  matlab.ui.control.DropDown
        UITable2                  matlab.ui.control.Table
        SummaryTab                matlab.ui.container.Tab
        ControlPanel              matlab.ui.container.Panel
        AnchorButtonGroup         matlab.ui.container.ButtonGroup
        EndButton                 matlab.ui.control.ToggleButton
        StartButton               matlab.ui.control.ToggleButton
        RankbyButtonGroup         matlab.ui.container.ButtonGroup
        EventonsetButton          matlab.ui.control.ToggleButton
        EventdurationButton       matlab.ui.control.ToggleButton
        EventDropDown             matlab.ui.control.DropDown
        EventDropDownLabel        matlab.ui.control.Label
        TableDropDown             matlab.ui.control.DropDown
        TableDropDownLabel        matlab.ui.control.Label
        PlotButton                matlab.ui.control.Button
        AfterEditField_2          matlab.ui.control.NumericEditField
        AfterEditField_2Label     matlab.ui.control.Label
        PreEditField              matlab.ui.control.NumericEditField
        PreEditFieldLabel         matlab.ui.control.Label
        UITable3                  matlab.ui.control.Table
        PlotsPanel                matlab.ui.container.Panel
    end

    
    properties (Access = private)
        curr_file
        curr_mice
        curr_table
        curr_event
        curr_trace
        curr_signal
        curr_record
        modify_table
        mode
    end
    
    methods (Access = private)
        
        function creat_record(app)
            LampColor=[0,1,0];
            % check mice_obj
            if (isempty(app.batchEditField.Value) && isempty(app.experimentEditField.Value) &&...
                    isempty(app.mice_idEditField.Value))==0
                app.curr_mice=Mice(app.batchEditField.Value,app.experimentEditField.Value,app.mice_idEditField.Value);
            else 
                LampColor=[0.85,0.33,0.1];
            end
            % check event obj
                % whether table valid 
            app.test_table;
            if ~isempty(app.curr_table)
               app.curr_event=mice_event(app.curr_table);
            else
               LampColor=[0.85,0.33,0.1];
            end
            
            % check signal_obj
            if ~isempty(app.curr_trace) && (app.SamplerateEditField.Value>0)
                app.curr_signal=mice_signal(app.curr_trace,app.SamplerateEditField.Value);
            else 
               LampColor=[0.85,0.33,0.1]; 
            end
            if ~isempty(app.curr_mice) && ~isempty(app.curr_event) && ~isempty(app.curr_signal)
                % complete
                app.curr_record=mice_record(app.curr_mice,app.curr_event,app.curr_signal);
            else 
                LampColor=[0.85,0.33,0.1]; 
            end
            % green light
            app.CompleteLamp.Color=LampColor;
        end
        
        function output = parse_eventtag(~,eventtag_obj)
            eventname=eventtag_obj.eventname;
            eventlist=eventtag_obj.eventlist;
            output=[];
            for i=1:length(eventname)
                curr_eventlist=eventlist(:,(i*2-1):i*2);
                curr_eventlist=curr_eventlist((curr_eventlist(:,1)~=0 & curr_eventlist(:,2)~=0),:);
                curr_eventname=repmat(eventname(i),length(curr_eventlist(:,1)),1);
                current_table=table(curr_eventlist(:,1),curr_eventlist(:,2),curr_eventname,'VariableNames',{'start','end','eventname'});
                output=[output;current_table]; %#ok<AGROW> 
            end
        end
        
        function test_table(app)
           passed=zeros(length(app.curr_table),1);
           for i=1:length(app.curr_table)
             checking=app.curr_table{i};
             [nrow,ncol]=size(checking);
             if nrow>0 && ncol>0
               passed(i)=1;
             end
           end
           app.curr_table=app.curr_table(passed==1);
        end
        
        function refresh_modify_dropdown(app)
            % set table number
            current_table_num=str2double(app.DropDown_3.Value);  
            % set selected table
            current_table=app.curr_record.event.tables{current_table_num};
            % set dropdown
            right_items=unique(current_table.eventname);
            app.modify_table=current_table;
            app.DropDown.Items=right_items; 
            app.EventDropDown.Items=right_items;
            % update uitable
            app.refresh_modify_table;
        end
        
        function refresh_summary_dropdown(app)
            % set table number
            current_table_num=str2double(app.TableDropDown.Value);  
            % set selected table
            current_table=app.curr_record.event.tables{current_table_num};
            % set dropdown
            right_items=unique(current_table.eventname);
            app.EventDropDown.Items=right_items; 
        end
        
        function refresh_modify_table(app)
            selected_event=app.DropDown.Value;
            selected_idx=cellfun(@(x) isequal(x,selected_event),app.modify_table.eventname);
            app.UITable2.Data=app.modify_table(selected_idx,:);
        end
        
        function plot_modify(app,indices)
            % zoomed trace
            fs=app.curr_record.signal.fs;
            trace=app.curr_record.signal.trace;
            trace_size=[min(trace),max(trace)];
            curr_start=floor((app.UITable2.Data.start(indices(1))-app.PreEditField_2.Value)*fs)+1;
            curr_stop=floor((app.UITable2.Data.end(indices(1))+app.AfterEditField.Value)*fs)+1;
            curr_duration=(app.UITable2.Data.end(indices(1))-app.UITable2.Data.start(indices(1)))*fs;
            % test valid
            valid=[curr_start>0,curr_stop<=length(trace)];
            if valid(1)==1 && valid(2)==1 
                    curr_zoom=trace(curr_start:curr_stop);
                    curr_zoom_line=[app.PreEditField_2.Value*fs,curr_duration+(app.PreEditField_2.Value*fs)];
            elseif valid(1)==0 && valid(2)==1
                    curr_zoom=trace(1:curr_stop);
                    curr_zoom_line=[app.UITable2.Data.start(indices(1))*fs,(curr_duration+app.UITable2.Data.start(indices(1))*fs)];
            elseif valid(1)==1 && valid(2)==0
                    curr_zoom=trace(curr_start:length(trace));
                    curr_zoom_line=[app.PreEditField_2.Value*fs,...
                    curr_duration+app.PreEditField_2.Value*fs];
            else 
                    curr_zoom=trace;
                    curr_zoom_line=[app.UITable2.Data.start(indices(1))*fs,...
                    curr_duration+app.PreEditField_2.Value*fs];
            end
            plot(app.UIAxes3,curr_zoom);
            % show event field zoomed
            line(app.UIAxes3,[curr_zoom_line(1),curr_zoom_line(1)],...
                [min(curr_zoom)-1,max(curr_zoom)+1])
            line(app.UIAxes3,[curr_zoom_line(2),curr_zoom_line(2)],...
                [min(curr_zoom)-1,max(curr_zoom)+1])
            % show full field
            plot(app.UIAxes2,app.curr_record.signal.trace) 
            line(app.UIAxes2,[floor((app.UITable2.Data.start(indices(1)))*fs)+1,...
                floor((app.UITable2.Data.start(indices(1)))*fs)+1],...
                [trace_size(1)-1,trace_size(2)+1],'color','r')
            hold(app.UIAxes2,'on');
            line(app.UIAxes2,[floor((app.UITable2.Data.end(indices(1)))*fs)+1,...
                floor((app.UITable2.Data.end(indices(1)))*fs)+1],...
                [trace_size(1)-1,trace_size(2)+1],'color','r')
            hold(app.UIAxes2,'off');

        end
        
        function refresh_select(app)
            selected_event=app.DropDown.Value;
            selected_idx=cellfun(@(x) isequal(x,selected_event),app.modify_table.eventname);
            selected=app.UITable2.Data.selected;
            % set table number
            % set selected table
            current_table=app.modify_table;
            current_table.selected(selected_idx)=selected;
            % refresh event table
            app.modify_table=current_table;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreatButton
        function CreatButtonPushed(app, event)
            app.creat_record;
            % set drop values
            app.DropDown_3.Items=string(1:app.curr_record.event.n_table);
            app.TableDropDown.Items=string(1:app.curr_record.event.n_table);
            app.refresh_modify_dropdown;
            app.refresh_modify_table;
        end

        % Button pushed function: LoadcsvButton
        function LoadcsvButtonPushed(app, event)

            [filename,pathname]=uigetfile('*.csv','pick a csv file contain signal');
            if isequal(0,filename) || isequal(0,pathname)
                
            else   
                app.curr_file=csvread(fullfile(pathname,filename));
                [~,ncol]=size(app.curr_file);
                app.ColumnDropDown.Items=string(1:ncol);
            end
        end

        % Value changed function: ColumnDropDown
        function ColumnDropDownValueChanged(app, event)
           value = str2double(app.ColumnDropDown.Value);
            try
                app.curr_trace=app.curr_file(:,value);
                plot(app.UIAxes,app.curr_trace)
            catch
                warning('no trace exist')
            end
        end

        % Button pushed function: Loadevent_tagButton
        function Loadevent_tagButtonPushed(app, event)
            curr_page=str2double(app.PageDropDown.Value);
            [filename,pathname]=uigetfile('*.mat','pick a mat file contain eventtags');
            if isequal(0,filename) || isequal(0,pathname) 
            else   
                event_tag_obj=load(fullfile(pathname,filename));
                objnames=fieldnames(event_tag_obj);
                if isequal(objnames{1},'eventtags')
                   app.curr_table{curr_page}=app.parse_eventtag(event_tag_obj.eventtags);
                   app.UITable.Data=app.curr_table{curr_page};
                end
            end
        end

        % Callback function
        function DropDown_2ValueChanged(app, event)

            
        end

        % Value changed function: PageDropDown
        function PageDropDownValueChanged(app, event)
            value = app.PageDropDown.Value;
            n_table=length(app.PageDropDown.Items)-1;
            if isequal(value,'new')
                app.PageDropDown.Items=[app.PageDropDown.Items(1:(n_table)),string(n_table+1),'new'];
                app.PageDropDown.Value=app.PageDropDown.Items(n_table); 
                app.curr_table=[app.curr_table,{''}];
            else
                app.UITable.Data=app.curr_table(str2double(value));
            end
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            if ~isempty(app.curr_record)
                value = app.Knob_2.Value;
                if app.SplineButton.Value==1
                    detrend_method='spline';
                else
                    detrend_method='polyfit';
                end
                app.curr_record.signal.detrend(detrend_method,value);
                app.curr_record.signal.plot(app.UIAxes4)
                app.DetrendLamp.Color=[0,1,0];
            else
                app.DetrendLamp.Color=[1,0.41,0.16];
            end
            
        end

        % Value changing function: Knob_2
        function Knob_2ValueChanging(app, event)

        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            
            app.refresh_modify_dropdown;
            app.DropDown.Value=app.DropDown.Items(1);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.refresh_modify_table;
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            app.plot_modify(indices);
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.UITable2.Data.selected(1:end)=true;
            app.refresh_select;
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            app.refresh_select
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % set table number
            current_table_num=str2double(app.DropDown_3.Value);  
            % set selected table
            app.curr_record.event.tables{current_table_num}=app.modify_table;
            % set lamp
            app.Lamp.Color=[0,1,0];
        end

        % Button pushed function: NoneButton
        function NoneButtonPushed(app, event)
            app.UITable2.Data.selected(1:end)=false;
            app.refresh_select;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            current_suites=card_suite(app.curr_record);
            if app.StartButton.Value==1
                current_anchor='start';
            elseif app.EndButton.Value==1
                current_anchor='end';
            end
            
        % set window
            window=[-app.PreEditField.Value,app.AfterEditField_2.Value];
            
        % set event 
            table_num=str2double(app.TableDropDown.Value);
            selected_event=app.EventDropDown.Value;
        % get matrix
            if app.EventdurationButton.Value==1
                event_type='Event duration';
            elseif app.EventonsetButton.Value==1
                event_type='Event onset';
            end
            current_matrix=current_suites.get_matrix(window,table_num,selected_event,current_anchor,event_type); 
            app.UITable3.Data=current_matrix.rowdata;
            app.UITable3.ColumnName=current_matrix.rowdata.Properties.VariableNames;
        % plot 
            app.PlotsPanel.AutoResizeChildren = 'off';
            current_suites.show(window,table_num,selected_event,current_anchor,event_type,app.PlotsPanel) 
            
        
        end

        % Value changed function: TableDropDown
        function TableDropDownValueChanged(app, event)
                 app.refresh_summary_dropdown;
        end

        % Menu selected function: CardMenu
        function CardMenuSelected(app, event)
            app.TabGroup.Visible=true;
            app.mode='card';
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            switch app.mode
                case 'card'
                    mice_record_obj=app.curr_record; %#ok<NASGU> 
                    uisave('mice_record_obj')
            end
        end

        % Button down function: CreatTab
        function CreatTabButtonDown(app, event)

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename,pathname]=uigetfile;  
            obj_loaded=load(fullfile(pathname,filename));
            obj_class=fieldnames(obj_loaded);
            switch obj_class{1}
                case 'mice_record_obj'
                    app.curr_record=obj_loaded.mice_record_obj;
                    app.DropDown_3.Items=string(1:app.curr_record.event.n_table);
                    app.TableDropDown.Items=string(1:app.curr_record.event.n_table);
                    app.refresh_modify_dropdown;
                    app.refresh_modify_table;
            end
        end

        % Button pushed function: LoadtableButton
        function LoadtableButtonPushed(app, event)
            curr_page=str2double(app.PageDropDown.Value);
            [filename,pathname]=uigetfile('*.csv','pick a table contain events');
            if isequal(0,filename) || isequal(0,pathname) 
            else   
                tables=readtable(fullfile(pathname,filename),'VariableNamingRule','preserve');
                app.curr_table{curr_page}=tables;
                app.UITable.Data=app.curr_table{curr_page};
            end 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SuiteMenu = uimenu(app.FileMenu);
            app.SuiteMenu.Text = 'Suite';
            app.CardMenu = uimenu(app.FileMenu);
            app.CardMenu.MenuSelectedFcn = createCallbackFcn(app, @CardMenuSelected, true);
            app.CardMenu.Text = 'Card';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.LinkvideoMenu = uimenu(app.ToolsMenu);
            app.LinkvideoMenu.Text = 'Link video';
            app.EventfilterMenu = uimenu(app.ToolsMenu);
            app.EventfilterMenu.Text = 'Event filter';
            app.PeakanalysisMenu = uimenu(app.ToolsMenu);
            app.PeakanalysisMenu.Text = 'Peak analysis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.CreatTab = uitab(app.TabGroup);
            app.CreatTab.Title = 'Creat';
            app.CreatTab.ButtonDownFcn = createCallbackFcn(app, @CreatTabButtonDown, true);
            app.MicePanel = uipanel(app.CreatTab);
            app.MicePanel.Title = 'Mice';
            app.batchEditFieldLabel = uilabel(app.MicePanel);
            app.batchEditFieldLabel.Text = 'batch';
            app.batchEditField = uieditfield(app.MicePanel, 'text');
            app.experimentEditFieldLabel = uilabel(app.MicePanel);
            app.experimentEditFieldLabel.Text = 'experiment';
            app.experimentEditField = uieditfield(app.MicePanel, 'text');
            app.mice_idEditFieldLabel = uilabel(app.MicePanel);
            app.mice_idEditFieldLabel.Text = 'mice_id';
            app.mice_idEditField = uieditfield(app.MicePanel, 'text');
            app.SignalPanel = uipanel(app.CreatTab);
            app.SignalPanel.Title = 'Signal';
            app.UIAxes = uiaxes(app.SignalPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadcsvButton = uibutton(app.SignalPanel, 'push');
            app.LoadcsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcsvButtonPushed, true);
            app.LoadcsvButton.Text = 'Load csv';
            app.ColumnDropDownLabel = uilabel(app.SignalPanel);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.SignalPanel);
            app.ColumnDropDown.Items = {'1'};
            app.ColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnDropDownValueChanged, true);
            app.ColumnDropDown.Value = '1';
            app.SamplerateEditFieldLabel = uilabel(app.SignalPanel);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.SamplerateEditField = uieditfield(app.SignalPanel, 'numeric');
            app.SamplerateEditField.Value = 50;
            app.EventPanel = uipanel(app.CreatTab);
            app.EventPanel.Title = 'Event';
            app.UITable = uitable(app.EventPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Loadevent_tagButton = uibutton(app.EventPanel, 'push');
            app.Loadevent_tagButton.ButtonPushedFcn = createCallbackFcn(app, @Loadevent_tagButtonPushed, true);
            app.Loadevent_tagButton.Text = 'Load event_tag';
            app.AddButton = uibutton(app.EventPanel, 'push');
            app.AddButton.Text = 'Add';
            app.NameEditFieldLabel = uilabel(app.EventPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.EventPanel, 'text');
            app.StartEditFieldLabel = uilabel(app.EventPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.EventPanel, 'numeric');
            app.EndEditFieldLabel = uilabel(app.EventPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.EventPanel, 'numeric');
            app.LoadtableButton = uibutton(app.EventPanel, 'push');
            app.LoadtableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtableButtonPushed, true);
            app.LoadtableButton.Text = 'Load table';
            app.PageDropDownLabel = uilabel(app.EventPanel);
            app.PageDropDownLabel.Text = 'Page';
            app.PageDropDown = uidropdown(app.EventPanel);
            app.PageDropDown.Items = {'1', 'new'};
            app.PageDropDown.ValueChangedFcn = createCallbackFcn(app, @PageDropDownValueChanged, true);
            app.PageDropDown.Value = '1';
            app.CompleteLampLabel = uilabel(app.CreatTab);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.CreatTab);
            app.CreatButton = uibutton(app.CreatTab, 'push');
            app.CreatButton.ButtonPushedFcn = createCallbackFcn(app, @CreatButtonPushed, true);
            app.CreatButton.Text = 'Creat';
            app.ModifyTab = uitab(app.TabGroup);
            app.ModifyTab.Title = 'Modify';
            app.EventPanel_2 = uipanel(app.ModifyTab);
            app.EventPanel_2.Title = 'Event';
            app.UITable2 = uitable(app.EventPanel_2);
            app.UITable2.ColumnName = {'start'; 'end'; 'name'; 'selected'; 'value'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false false true false];
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.DropDown = uidropdown(app.EventPanel_2);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.AllButton = uibutton(app.EventPanel_2, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.NoneButton = uibutton(app.EventPanel_2, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @NoneButtonPushed, true);
            app.NoneButton.Text = 'None';
            app.DropDown_3 = uidropdown(app.EventPanel_2);
            app.DropDown_3.Items = {'1'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '1';
            app.SignalPanel_2 = uipanel(app.ModifyTab);
            app.SignalPanel_2.Title = 'Signal';
            app.UIAxes2 = uiaxes(app.SignalPanel_2);
            title(app.UIAxes2, 'Title')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.SignalPanel_2);
            title(app.UIAxes3, 'Title')
            zlabel(app.UIAxes3, 'Z')
            app.PreEditField_2Label = uilabel(app.SignalPanel_2);
            app.PreEditField_2Label.Text = 'Pre';
            app.PreEditField_2 = uieditfield(app.SignalPanel_2, 'numeric');
            app.PreEditField_2.Value = 5;
            app.AfterEditFieldLabel = uilabel(app.SignalPanel_2);
            app.AfterEditFieldLabel.Text = 'After';
            app.AfterEditField = uieditfield(app.SignalPanel_2, 'numeric');
            app.AfterEditField.Value = 5;
            app.ConfirmButton = uibutton(app.SignalPanel_2, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.DetrenderPanel = uipanel(app.ModifyTab);
            app.DetrenderPanel.Title = 'Detrender';
            app.UIAxes4 = uiaxes(app.DetrenderPanel);
            title(app.UIAxes4, 'Title')
            zlabel(app.UIAxes4, 'Z')
            app.Knob_2 = uiknob(app.DetrenderPanel, 'continuous');
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.ValueChangingFcn = createCallbackFcn(app, @Knob_2ValueChanging, true);
            app.MethodButtonGroup = uibuttongroup(app.DetrenderPanel);
            app.MethodButtonGroup.Title = 'Method';
            app.PolyfitButton = uitogglebutton(app.MethodButtonGroup);
            app.PolyfitButton.Text = 'Polyfit';
            app.PolyfitButton.Value = true;
            app.SplineButton = uitogglebutton(app.MethodButtonGroup);
            app.SplineButton.Text = 'Spline';
            app.DetrendLampLabel = uilabel(app.DetrenderPanel);
            app.DetrendLampLabel.Text = 'Detrend';
            app.DetrendLamp = uilamp(app.DetrenderPanel);
            app.Lamp = uilamp(app.ModifyTab);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.PlotsPanel = uipanel(app.SummaryTab);
            app.PlotsPanel.Title = 'Plots';
            app.UITable3 = uitable(app.SummaryTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.ControlPanel = uipanel(app.SummaryTab);
            app.ControlPanel.Title = 'Control ';
            app.PreEditFieldLabel = uilabel(app.ControlPanel);
            app.PreEditFieldLabel.Text = 'Pre';
            app.PreEditField = uieditfield(app.ControlPanel, 'numeric');
            app.PreEditField.Value = 5;
            app.AfterEditField_2Label = uilabel(app.ControlPanel);
            app.AfterEditField_2Label.Text = 'After';
            app.AfterEditField_2 = uieditfield(app.ControlPanel, 'numeric');
            app.AfterEditField_2.Value = 5;
            app.PlotButton = uibutton(app.ControlPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TableDropDownLabel = uilabel(app.ControlPanel);
            app.TableDropDownLabel.Text = 'Table';
            app.TableDropDown = uidropdown(app.ControlPanel);
            app.TableDropDown.ValueChangedFcn = createCallbackFcn(app, @TableDropDownValueChanged, true);
            app.EventDropDownLabel = uilabel(app.ControlPanel);
            app.EventDropDownLabel.Text = 'Event';
            app.EventDropDown = uidropdown(app.ControlPanel);
            app.RankbyButtonGroup = uibuttongroup(app.ControlPanel);
            app.RankbyButtonGroup.Title = 'Rank by';
            app.EventdurationButton = uitogglebutton(app.RankbyButtonGroup);
            app.EventdurationButton.Text = 'Event duration';
            app.EventdurationButton.Value = true;
            app.EventonsetButton = uitogglebutton(app.RankbyButtonGroup);
            app.EventonsetButton.Text = 'Event onset';
            app.AnchorButtonGroup = uibuttongroup(app.ControlPanel);
            app.AnchorButtonGroup.Title = 'Anchor';
            app.StartButton = uitogglebutton(app.AnchorButtonGroup);
            app.StartButton.Text = 'Start';
            app.StartButton.Value = true;
            app.EndButton = uitogglebutton(app.AnchorButtonGroup);
            app.EndButton.Text = 'End';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Visible = 'off';
            app.CreatTab_2 = uitab(app.TabGroup2);
            app.CreatTab_2.Title = 'Creat';
            app.UITable4 = uitable(app.CreatTab_2);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fe_app_oop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end